classdef DialogAppImgDataDiffractionLimited < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        PushTool                matlab.ui.container.toolbar.PushTool
        PushTool2               matlab.ui.container.toolbar.PushTool
        FormatDropDown          matlab.ui.control.DropDown
        FormatDropDownLabel     matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        MaxFrameLabelEditField  matlab.ui.control.NumericEditField
        MinFrameLabelEditField  matlab.ui.control.NumericEditField
        FrameSlider             matlab.ui.control.Slider
        GotoframeSpinner        matlab.ui.control.Spinner
        GotoframeSpinnerLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ContextMenu3            matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        settings % struct, information about dataset and class instances for processing
        tr % Tiff object for reading stack
        currentFrame % index of current frame being displayed
        currentImageUnprocessed % current unprocessed frame
        currentImg % current processed frame being displayed
        Proc % instance of the class Process

    end
    
    methods (Access = private)

        function [] = moveToSpecificFrame(app,frameNumber)
            if (frameNumber > 1) && (frameNumber < app.CallingApp.numFrames)
                app.tr.setDirectory(frameNumber);
                img = double(app.tr.read());
                img = app.Proc.makeDimensionsEven(img);
                img = (img - app.settings.camOffset)./(app.settings.camGain*app.settings.QE);
                app.currentImageUnprocessed = img;
                convertCurrentImg(app);
                imagesc(app.UIAxes,app.currentImg);
            end
        end

        function [] = moveToNextFrame(app)
            if app.GotoframeSpinner.Value < app.CallingApp.numFrames
                newFrame = app.GotoframeSpinner.Value + 1;
                moveToSpecificFrame(app,newFrame);
                app.GotoframeSpinner.Value = newFrame;
                app.FrameSlider.Value      = newFrame;
                pause(0.05)
            end
        end
        
        function [] = moveToPreviousFrame(app)
            if  app.GotoframeSpinner.Value > 1
                newFrame = app.GotoframeSpinner.Value - 1;
                moveToSpecificFrame(app,newFrame);
                app.GotoframeSpinner.Value = newFrame;
                app.FrameSlider.Value      = newFrame;
                pause(0.05)
            end
        end

        function [] = rerenderCurrentFrame(app)
            convertCurrentImg(app);
            imagesc(app.UIAxes,app.currentImg);
        end
        
        function [] = prepareForConversionStack(app)
            %PREPAREFORCONVERSIONSTACK
            % check if an image is loaded
            try testImg = imread(app.CallingApp.filelist(1).filepath);
            catch; writeToErrorLog(app.CallingApp,'ERROR: No images are loaded.'); return; end
            
            % open parameter settings dialog app
            app.CallingApp.DialogSettingsDiffr = DialogAppSettingsDiffractionLimited(app.CallingApp);
            while isvalid(app.CallingApp.DialogSettingsDiffr); pause(0.1); end % wait until window closes
            
            if app.CallingApp.settingsDiffrLoaded % if settings were loaded properly
                
                % update settings with dimensions of images that are currently loaded
                [nx,ny] = size(testImg);
                if mod(nx,2); nx = nx - 1; end % round down to nearest even number (software will force to be even)
                if mod(ny,2); ny = ny - 1; end % round down to nearest even number (software will force to be even)
                app.CallingApp.settingsDiffr.imgSize = [nx,ny];

                % get a copy of the current settings
                app.settings = app.CallingApp.settingsDiffr;

                % create instances of the Camera, Microscope and Process classes
                app.Proc = createInstanceProcessForDiffrProcessing(app);

                % create a Tiff object for reading loaded images and read first frame
                app.tr = Tiff(app.CallingApp.filelist(1).filepath,'r');
                firstFrame = double(app.tr.read());
                firstFrame = (firstFrame - app.settings.camOffset)./(app.settings.camGain*app.settings.QE);
                firstFrame = app.Proc.makeDimensionsEven(firstFrame);
                app.currentImageUnprocessed = firstFrame;
                
%                 % set gui limit field values
%                 app.DoLPmin = 0;
%                 app.DoLPmax = 1;
%                 app.S0min = min(app.currentImageUnprocessed(:));
%                 app.S0max = max(app.currentImageUnprocessed(:));
%                 app.DoLPminEditField.Value = app.DoLPmin;
%                 app.DoLPmaxEditField.Value = app.DoLPmax;
%                 app.S0minEditField.Value   = app.S0min;
%                 app.S0maxEditField.Value   = app.S0max;

                % process first frame
                convertCurrentImg(app);
            else
                writeToErrorLog(app,"ERROR: Settings were not saved. Try again.")
            end
        end
        
        function Proc = createInstanceProcessForDiffrProcessing(app)                
            Cam = Camera(app.settings.camPixSize, app.settings.imgSize,app.settings.mosaicUnitArray,app.settings.camOffset,app.settings.camGain,1,app.settings.QE);
            Scope = Microscope(Cam,0,app.settings.magnification,0);
            locParamsDummy.w.xy = 0; locParamsDummy.w.phi = 0; locParamsDummy.w.theta = 0;
            Proc = Process(Scope,Cam,app.settings.bkgndEstimationParams,app.settings.methodInterpolation,locParamsDummy);
        end

        function [] = convertCurrentImg(app)

            if strcmp(app.FormatDropDown.Value,'Unprocessed')
                app.currentImg = app.currentImageUnprocessed;

            else
                [I0,Ip45,I90,In45] = app.Proc.channelsFromUnprocessed(app.currentImageUnprocessed);
                I0   = app.removeOnePixelBorder(I0);
                Ip45 = app.removeOnePixelBorder(Ip45);
                I90  = app.removeOnePixelBorder(I90);
                In45 = app.removeOnePixelBorder(In45);

                switch app.FormatDropDown.Value

                    case 'Channels'
                        app.currentImg = [[I0 Ip45];[I90 In45]];

                    case 'S0 (Intensity)'
                        app.currentImg = (I0 + Ip45 + I90 + In45)/2;

                    case 'S1'
                        app.currentImg = I0 - I90;

                    case 'S2'
                        app.currentImg = Ip45 - In45;

                    case 'DoLP'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = sqrt((S1.^2 + S2.^2)./(S0.^2));

                    case 'AoLP'
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        app.currentImg = (1/2)*atan2(S2,S1);

                    case 'AoLP/DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        AoLP = (1/2)*atan2(S2,S1);
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        DoLP_min = 0;
                        DoLP_max = 1;

                        S0_min = min(S0(:));
                        S0_max = max(S0(:));

                        H = (AoLP + pi/2)/pi;
                        S = (DoLP - DoLP_min)./(DoLP_max - DoLP_min); S(S<0) = 0; S(S>1) = 1;
                        V = (S0 - S0_min)./(S0_max - S0_min);         V(V<0) = 0; V(V>1) = 1;

                        app.currentImg = hsv2rgb(H,S,V);

                    case 'DoLP/S0'
                        S0 = (I0 + Ip45 + I90 + In45)/2;
                        S1 = I0 - I90;
                        S2 = Ip45 - In45;
                        DoLP = sqrt((S1.^2 + S2.^2)./(S0.^2));

                        S0_min = 0;
                        S0_max = 1;
                        weight = (S0 - S0_min)./(S0_max - S0_min);
                        weight(weight < 0) = 0; weight(weight > 1) = 1;

                        img = uint8(round(DoLP*255));
                        rgb = ind2rgb(img, parula(255));
                        rgb(:,:,1) = rgb(:,:,1).*weight;
                        rgb(:,:,2) = rgb(:,:,2).*weight;
                        rgb(:,:,3) = rgb(:,:,3).*weight;
                        app.currentImg = rgb;
                end
            end
        end

    end

    methods (Static)

        function img = removeOnePixelBorder(img)
            img = img(2:end-1,2:end-1);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp; % Store main app in property for CloseRequestFcn to use
            
            app.currentFrame = 1;
            
            app.MaxFrameLabelEditField.Value = app.CallingApp.numFrames;
            app.FrameSlider.Limits      = [1 app.CallingApp.numFrames]; 
            app.GotoframeSpinner.Limits = [1 app.CallingApp.numFrames];

            % get processing settings
            app.settings = struct;

            prepareForConversionStack(app);

            imagesc(app.UIAxes,app.currentImg); 
            xlabel(app.UIAxes,'x (pix)');
            ylabel(app.UIAxes,'y (pix)');
            
            c = colorbar(app.UIAxes);
            c.Label.String = 'Counts (ADU)';
            c.TickDirection = 'out'; 
            colormap(app.UIAxes,"gray")

            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                moveToPreviousFrame(app);
            end
            if strcmp(key,'rightarrow')
                moveToNextFrame(app);
            end
        end

        % Value changed function: GotoframeSpinner
        function GotoframeSpinnerValueChanged(app, event)
            app.FrameSlider.Value = app.GotoframeSpinner.Value;
            moveToSpecificFrame(app, app.GotoframeSpinner.Value);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.GotoframeSpinner.Value = round(app.FrameSlider.Value);
            moveToSpecificFrame(app,round(app.FrameSlider.Value));
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            rerenderCurrentFrame(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.GotoframeSpinnerLabel = uilabel(app.Panel);
            app.GotoframeSpinnerLabel.Text = 'Go to frame:';
            app.GotoframeSpinner = uispinner(app.Panel);
            app.GotoframeSpinner.Limits = [1 501];
            app.GotoframeSpinner.ValueChangedFcn = createCallbackFcn(app, @GotoframeSpinnerValueChanged, true);
            app.GotoframeSpinner.Value = 1;
            app.FrameSlider = uislider(app.Panel);
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [];
            app.MinFrameLabelEditField = uieditfield(app.Panel, 'numeric');
            app.MinFrameLabelEditField.Editable = 'off';
            app.MinFrameLabelEditField.Value = 1;
            app.MaxFrameLabelEditField = uieditfield(app.Panel, 'numeric');
            app.MaxFrameLabelEditField.Editable = 'off';
            app.MaxFrameLabelEditField.Value = 100;
            app.FormatDropDownLabel = uilabel(app.UIFigure);
            app.FormatDropDownLabel.Text = 'Format:';
            app.FormatDropDown = uidropdown(app.UIFigure);
            app.FormatDropDown.Items = {'Unprocessed', 'Channels', 'S0 (Intensity)', 'S1', 'S2', 'AoLP', 'DoLP', 'AoLP/DoLP/S0', 'DoLP/S0'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'Channels';
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppImgDataDiffractionLimited(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end