classdef DialogAppFiltering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberoflocalisationsafterfilteringEditField  matlab.ui.control.NumericEditField
        NumberoflocalisationsafterfilteringEditFieldLabel  matlab.ui.control.Label
        NumberoflocalisationsbeforefilteringEditField  matlab.ui.control.NumericEditField
        NumberoflocalisationsbeforefilteringEditFieldLabel  matlab.ui.control.Label
        RestoredefaultsButton         matlab.ui.control.Button
        ClearallfieldsButton          matlab.ui.control.Button
        FilteringparametersPanel      matlab.ui.container.Panel
        ClusterIdMinEditField         matlab.ui.control.NumericEditField
        FramesboundLabel_2            matlab.ui.control.Label
        ClusterIdMaxEditField         matlab.ui.control.NumericEditField
        FramesBoundMinEditField       matlab.ui.control.NumericEditField
        FramesboundLabel              matlab.ui.control.Label
        FramesBoundMaxEditField       matlab.ui.control.NumericEditField
        MaxLabel                      matlab.ui.control.Label
        MinLabel                      matlab.ui.control.Label
        UncertaintyxyMaxEditField     matlab.ui.control.NumericEditField
        avgDoLPMaxEditField           matlab.ui.control.NumericEditField
        SigmayMaxEditField            matlab.ui.control.NumericEditField
        SigmaxMaxEditField            matlab.ui.control.NumericEditField
        BkgndMaxEditField             matlab.ui.control.NumericEditField
        PhotonsMaxEditField           matlab.ui.control.NumericEditField
        netDoLPMaxEditField           matlab.ui.control.NumericEditField
        ThetaMaxEditField             matlab.ui.control.NumericEditField
        FrameMaxEditField             matlab.ui.control.NumericEditField
        PhiMaxEditField               matlab.ui.control.NumericEditField
        yMaxEditField                 matlab.ui.control.NumericEditField
        xMaxEditField                 matlab.ui.control.NumericEditField
        UncertaintyxyMinEditField     matlab.ui.control.NumericEditField
        UncertaintyxyEditFieldLabel   matlab.ui.control.Label
        SigmayMinEditField            matlab.ui.control.NumericEditField
        SigmayEditFieldLabel          matlab.ui.control.Label
        SigmaxMinEditField            matlab.ui.control.NumericEditField
        SigmaxEditFieldLabel          matlab.ui.control.Label
        BkgndMinEditField             matlab.ui.control.NumericEditField
        BkgndEditFieldLabel           matlab.ui.control.Label
        PhotonsMinEditField           matlab.ui.control.NumericEditField
        PhotonsEditFieldLabel         matlab.ui.control.Label
        netDoLPMinEditField           matlab.ui.control.NumericEditField
        netDolpEditFieldLabel         matlab.ui.control.Label
        avgDoLPMinEditField           matlab.ui.control.NumericEditField
        avgDoLPEditFieldLabel         matlab.ui.control.Label
        ThetaMinEditField             matlab.ui.control.NumericEditField
        ThetaEditFieldLabel           matlab.ui.control.Label
        PhiMinEditField               matlab.ui.control.NumericEditField
        PhiEditFieldLabel             matlab.ui.control.Label
        yMinEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xMinEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        FrameMinEditField             matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        SavefilteringButton           matlab.ui.control.Button
        PlotcolourcodedscatterplotPanel  matlab.ui.container.Panel
        PlotscatterplotButton         matlab.ui.control.Button
        ParameterScatterPlotDropDown  matlab.ui.control.DropDown
        ParameterDropDown_2Label      matlab.ui.control.Label
        Plot2DhistogramPanel          matlab.ui.container.Panel
        Plothistogram2DButton         matlab.ui.control.Button
        Parameter2Hist2DDropDown      matlab.ui.control.DropDown
        Parameter2DropDownLabel       matlab.ui.control.Label
        Parameter1Hist2DDropDown      matlab.ui.control.DropDown
        Parameter1DropDownLabel       matlab.ui.control.Label
        Plot1DhistogramPanel          matlab.ui.container.Panel
        Plothistogram1DButton         matlab.ui.control.Button
        ParameterHist1DDropDown       matlab.ui.control.DropDown
        ParameterLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp % Main app object
        
        paramScatterplot % dropdown option scatter plot
        paramHist1D % dropdown option 1D histogram
        param1Hist2D % dropdown option 1 2D histogram
        param2Hist2D % dropdown option 2 2D histogram
        
        keep % bool 1D array, 1 keep localisation, 0 remove localisation
        
        % minimum and maximum values of all parameters
        fileId_min
        fileId_max
        frame_min
        frame_max
        x_min
        x_max
        y_min
        y_max
        phi_min
        phi_max
        theta_min
        theta_max
        avgDoLP_min
        avgDoLP_max
        netDoLP_min
        netDoLP_max
        photons_min
        photons_max
        bkgnd_min
        bkgnd_max
        sigmax_min
        sigmax_max
        sigmay_min
        sigmay_max
        uncertainty_xy_min
        uncertainty_xy_max
        uncertainty_int_min
        uncertainty_int_max
        framesBound_min
        framesBound_max
        clusterId_min
        clusterId_max

    end
    
    methods (Access = private)

        function [] = updateKeep(app)
            %UPDATEKEEP
            % first update using parameters that all algorithms have in common
            keepBase = logical((app.CallingApp.locData.frameGrouped > app.frame_min).*...
                               (app.CallingApp.locData.frameGrouped < app.frame_max).*...
                               (app.CallingApp.locData.x            > app.x_min).*...
                               (app.CallingApp.locData.x            < app.x_max).*...
                               (app.CallingApp.locData.y            > app.y_min).*...
                               (app.CallingApp.locData.y            < app.y_max).*...
                               (app.CallingApp.locData.phi          > app.phi_min).*...
                               (app.CallingApp.locData.phi          < app.phi_max).*...
                               (app.CallingApp.locData.theta        > app.theta_min).*...
                               (app.CallingApp.locData.theta        < app.theta_max).*...
                               (app.CallingApp.locData.avgDoLP      > app.avgDoLP_min).*...
                               (app.CallingApp.locData.avgDoLP      < app.avgDoLP_max).*...
                               (app.CallingApp.locData.netDoLP      > app.netDoLP_min).*...
                               (app.CallingApp.locData.netDoLP      < app.netDoLP_max));
            % update further using algorithm-specific parameter
            switch app.CallingApp.localisationAlgorithm
                case 'centroid'                        
                    keepSpecific = (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max);
                case 'symmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.bkgnd_max).*...
                                   (app.CallingApp.locData.sigma   > app.sigmax_min).*...
                                   (app.CallingApp.locData.sigma   < app.sigmax_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.uncertainty_xy_max);
                case 'asymmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.bkgnd_max).*...
                                   (app.CallingApp.locData.sigmax  > app.sigmax_min).*...
                                   (app.CallingApp.locData.sigmax  < app.sigmax_max).*...
                                   (app.CallingApp.locData.sigmay  > app.sigmay_min).*...
                                   (app.CallingApp.locData.sigmay  < app.sigmay_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.uncertainty_xy_max);
                case 'rotated asymmetric gaussian'
                    keepSpecific = (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.photons > app.photons_min).*...
                                   (app.CallingApp.locData.photons < app.photons_max).*...
                                   (app.CallingApp.locData.bkgnd   > app.bkgnd_min).*...
                                   (app.CallingApp.locData.bkgnd   < app.bkgnd_max).*...
                                   (app.CallingApp.locData.sigmax  > app.sigmax_min).*...
                                   (app.CallingApp.locData.sigmax  < app.sigmax_max).*...
                                   (app.CallingApp.locData.sigmay  > app.sigmay_min).*...
                                   (app.CallingApp.locData.sigmay  < app.sigmay_max).*...
                                   (app.CallingApp.locData.uncertainty_xy > app.uncertainty_xy_min).*...
                                   (app.CallingApp.locData.uncertainty_xy < app.uncertainty_xy_max);
                otherwise
                    % write to log
            end

            keepTotal = keepBase.*keepSpecific;
            
            % if temporal grouping was performed
            if ismember('len',app.CallingApp.locData.Properties.VariableNames)
                keep_temp = (app.CallingApp.locData.len > app.framesBound_min).*...
                            (app.CallingApp.locData.len < app.framesBound_max);
                keepTotal = keepTotal.*keep_temp;
            end

            % if clustering was performed
            if ismember('cluster_id',app.CallingApp.locData.Properties.VariableNames)
                keep_cluster = (app.CallingApp.locData.cluster_id > app.clusterId_min).*...
                               (app.CallingApp.locData.cluster_id < app.clusterId_max);
                keepTotal = keepTotal.*keep_cluster;
            end

            app.CallingApp.locData.filter = logical(keepTotal); % combine
        end
        
        function [] = updateLimitsFromFieldValues(app)
            app.frame_min          = app.FrameMinEditField.Value;
            app.frame_max          = app.FrameMaxEditField.Value;
            app.x_min              = app.xMinEditField.Value;
            app.x_max              = app.xMaxEditField.Value;
            app.y_min              = app.yMinEditField.Value;
            app.y_max              = app.yMaxEditField.Value;
            app.phi_min            = app.PhiMinEditField.Value;
            app.phi_max            = app.PhiMaxEditField.Value;
            app.theta_min          = app.ThetaMinEditField.Value;
            app.theta_max          = app.ThetaMaxEditField.Value;
            app.avgDoLP_min        = app.avgDoLPMinEditField.Value;
            app.avgDoLP_max        = app.avgDoLPMaxEditField.Value;
            app.netDoLP_min        = app.netDoLPMinEditField.Value;
            app.netDoLP_max        = app.netDoLPMaxEditField.Value;
            app.photons_min        = app.PhotonsMinEditField.Value;
            app.photons_max        = app.PhotonsMaxEditField.Value;
            app.bkgnd_min          = app.BkgndMinEditField.Value;
            app.bkgnd_max          = app.BkgndMaxEditField.Value;
            app.sigmax_min         = app.SigmaxMinEditField.Value;
            app.sigmax_max         = app.SigmaxMaxEditField.Value;
            app.sigmay_min         = app.SigmayMinEditField.Value;
            app.sigmay_max         = app.SigmayMaxEditField.Value;
            app.uncertainty_xy_min = app.UncertaintyxyMinEditField.Value;
            app.uncertainty_xy_max = app.UncertaintyxyMaxEditField.Value;
            app.framesBound_min    = app.FramesBoundMinEditField.Value;
            app.framesBound_max    = app.FramesBoundMaxEditField.Value;
            app.clusterId_min      = app.ClusterIdMinEditField.Value;
            app.clusterId_max      = app.ClusterIdMaxEditField.Value;

        end

        function [] = updateFieldValuesFromLimits(app)
            app.FrameMinEditField.Value = app.frame_min;
            app.FrameMaxEditField.Value = app.frame_max;
            app.xMinEditField.Value = app.x_min;
            app.xMaxEditField.Value = app.x_max;
            app.yMinEditField.Value = app.y_min;
            app.yMaxEditField.Value = app.y_max;
            app.PhiMinEditField.Value = app.phi_min;
            app.PhiMaxEditField.Value = app.phi_max;
            app.ThetaMinEditField.Value = app.theta_min;
            app.ThetaMaxEditField.Value = app.theta_max;
            app.avgDoLPMinEditField.Value = app.avgDoLP_min;
            app.avgDoLPMaxEditField.Value = app.avgDoLP_max;
            app.netDoLPMinEditField.Value = app.netDoLP_min;
            app.netDoLPMaxEditField.Value = app.netDoLP_max;
            app.PhotonsMinEditField.Value = app.photons_min;
            app.PhotonsMaxEditField.Value = app.photons_max;
            app.BkgndMinEditField.Value = app.bkgnd_min;
            app.BkgndMaxEditField.Value = app.bkgnd_max;
            app.SigmaxMinEditField.Value = app.sigmax_min;
            app.SigmaxMaxEditField.Value = app.sigmax_max;
            app.SigmayMinEditField.Value = app.sigmay_min;
            app.SigmayMaxEditField.Value = app.sigmay_max;
            app.UncertaintyxyMinEditField.Value = app.uncertainty_xy_min;
            app.UncertaintyxyMaxEditField.Value = app.uncertainty_xy_max;
        end
        
        function [] = deleteLocalisationsExcludedByCurrentFilteringSettings(app)
            app.CallingApp.locData(app.CallingApp.locData.filter == 0,:) = [];
        end
        
        function [] = updateLocalisationNumbersDisplay(app)
            app.NumberoflocalisationsbeforefilteringEditField.Value = height(app.CallingApp.locData);
            app.NumberoflocalisationsafterfilteringEditField.Value  = sum(app.CallingApp.locData.filter);
        end
        
        function [] = enableCorrectFieldsForAlgorithm(app)
            %ENABLECORRECTFIELDSFORALGORITHM
            enableAllFilteringFields(app);
            app.CallingApp.localisationAlgorithm
            switch app.CallingApp.localisationAlgorithm
                case 'centroid'
                    set(app.BkgndMinEditField,        'Enable','off')
                    set(app.BkgndMaxEditField,        'Enable','off')
                    set(app.SigmaxMinEditField,       'Enable','off')
                    set(app.SigmaxMaxEditField,       'Enable','off')
                    set(app.SigmayMinEditField,       'Enable','off')
                    set(app.SigmayMaxEditField,       'Enable','off')
                    set(app.UncertaintyxyMinEditField,'Enable','off')
                    set(app.UncertaintyxyMaxEditField,'Enable','off')
                    set(app.UncertaintyxyMaxEditField,'Enable','off')

                case 'symmetric gaussian'
                    set(app.SigmayMinEditField,       'Enable','off')
                    set(app.SigmayMaxEditField,       'Enable','off')
            end
        end
        
        function [] = enableAllFilteringFields(app)
            %ENABLEALLFILTERINGFIELDS
            set(app.FrameMinEditField,        'Enable','on')
            set(app.FrameMaxEditField,        'Enable','on')
            set(app.xMinEditField,            'Enable','on')
            set(app.xMaxEditField,            'Enable','on')
            set(app.yMinEditField,            'Enable','on')
            set(app.yMaxEditField,            'Enable','on')
            set(app.PhiMinEditField,          'Enable','on')
            set(app.PhiMaxEditField,          'Enable','on')
            set(app.ThetaMinEditField,        'Enable','on')
            set(app.ThetaMaxEditField,        'Enable','on')
            set(app.avgDoLPMinEditField,      'Enable','on')
            set(app.avgDoLPMaxEditField,      'Enable','on')
            set(app.netDoLPMinEditField,      'Enable','on')
            set(app.netDoLPMaxEditField,      'Enable','on')
            set(app.ThetaMinEditField,        'Enable','on')
            set(app.ThetaMaxEditField,        'Enable','on')
            set(app.PhotonsMinEditField,      'Enable','on')
            set(app.PhotonsMaxEditField,      'Enable','on')
            set(app.BkgndMinEditField,        'Enable','on')
            set(app.BkgndMaxEditField,        'Enable','on')
            set(app.SigmaxMinEditField,       'Enable','on')
            set(app.SigmaxMaxEditField,       'Enable','on')
            set(app.SigmayMinEditField,       'Enable','on')
            set(app.SigmayMaxEditField,       'Enable','on')
            set(app.UncertaintyxyMinEditField,'Enable','on')
            set(app.UncertaintyxyMaxEditField,'Enable','on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.paramScatterplot = app.ParameterScatterPlotDropDown.Value;
            app.paramHist1D      = app.ParameterHist1DDropDown.Value;
            app.param1Hist2D     = app.Parameter1Hist2DDropDown.Value;
            app.param2Hist2D     = app.Parameter2Hist2DDropDown.Value;

            updateLimitsFromFieldValues(app);

            guessLocalisationAlgorithm(app.CallingApp);
            updateKeep(app); % apply default filtering
            updateLocalisationNumbersDisplay(app);
            enableCorrectFieldsForAlgorithm(app);
        end

        % Value changed function: ParameterScatterPlotDropDown
        function ParameterScatterPlotDropDownValueChanged(app, event)
            app.paramScatterplot = app.ParameterScatterPlotDropDown.Value;
        end

        % Value changed function: ParameterHist1DDropDown
        function ParameterHist1DDropDownValueChanged(app, event)
            app.paramHist1D = app.ParameterHist1DDropDown.Value;
        end

        % Value changed function: Parameter1Hist2DDropDown
        function Parameter1Hist2DDropDownValueChanged(app, event)
            app.param1Hist2D = app.Parameter1Hist2DDropDown.Value;
        end

        % Value changed function: Parameter2Hist2DDropDown
        function Parameter2Hist2DDropDownValueChanged(app, event)
            app.param2Hist2D = app.Parameter2Hist2DDropDown.Value;
        end

        % Button pushed function: PlotscatterplotButton
        function PlotscatterplotButtonPushed(app, event)
            % generate scatter plot
            Plot.FilteringApp.plotScatterPlot(app.CallingApp.locData,app.paramScatterplot,app.CallingApp.figureParams);
        end

        % Button pushed function: Plothistogram1DButton
        function Plothistogram1DButtonPushed(app, event)
            % generate 1D histogram
            Plot.FilteringApp.plot1DHistogram(app.CallingApp.locData,app.paramHist1D,app.CallingApp.figureParams);
        end

        % Button pushed function: Plothistogram2DButton
        function Plothistogram2DButtonPushed(app, event)
            % generate 2D histogram
            Plot.FilteringApp.plot2DHistogram(app.CallingApp.locData,app.param1Hist2D,app.param2Hist2D,app.CallingApp.figureParams);
        end

        % Value changed function: FrameMinEditField
        function FrameMinEditFieldValueChanged(app, event)
            app.frame_min = app.FrameMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FrameMaxEditField
        function FrameMaxEditFieldValueChanged(app, event)
            app.frame_max = app.FrameMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: xMinEditField
        function xMinEditFieldValueChanged(app, event)
            app.x_min = app.xMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: xMaxEditField
        function xMaxEditFieldValueChanged(app, event)
            app.x_max = app.xMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: yMinEditField
        function yMinEditFieldValueChanged(app, event)
            app.y_min = app.yMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: yMaxEditField
        function yMaxEditFieldValueChanged(app, event)
            app.y_max = app.yMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhiMinEditField
        function PhiMinEditFieldValueChanged(app, event)
            app.phi_min = app.PhiMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhiMaxEditField
        function PhiMaxEditFieldValueChanged(app, event)
            app.phi_max = app.PhiMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ThetaMinEditField
        function ThetaMinEditFieldValueChanged(app, event)
            app.theta_min = app.ThetaMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ThetaMaxEditField
        function ThetaMaxEditFieldValueChanged(app, event)
            app.theta_max = app.ThetaMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: avgDoLPMinEditField
        function avgDoLPMinEditFieldValueChanged(app, event)
            app.avgDoLP_min = app.avgDoLPMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: avgDoLPMaxEditField
        function avgDoLPMaxEditFieldValueChanged(app, event)
            app.avgDoLP_max = app.avgDoLPMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: netDoLPMinEditField
        function netDoLPMinEditFieldValueChanged(app, event)
            app.netDoLP_min = app.netDoLPMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: netDoLPMaxEditField
        function netDoLPMaxEditFieldValueChanged(app, event)
            app.netDoLP_max = app.netDoLPMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhotonsMinEditField
        function PhotonsMinEditFieldValueChanged(app, event)
            app.photons_min = app.PhotonsMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: PhotonsMaxEditField
        function PhotonsMaxEditFieldValueChanged(app, event)
            app.photons_max = app.PhotonsMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: BkgndMinEditField
        function BkgndMinEditFieldValueChanged(app, event)
            app.bkgnd_min = app.BkgndMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: BkgndMaxEditField
        function BkgndMaxEditFieldValueChanged(app, event)
            app.bkgnd_max = app.BkgndMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmaxMinEditField
        function SigmaxMinEditFieldValueChanged(app, event)
            app.sigmax_min = app.SigmaxMinEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.sigmay_min               = app.sigmax_min;
                app.SigmayMinEditField.Value = app.sigmay_min;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmaxMaxEditField
        function SigmaxMaxEditFieldValueChanged(app, event)
            app.sigmax_max = app.SigmaxMaxEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.sigmay_max               = app.sigmax_max;
                app.SigmayMaxEditField.Value = app.sigmay_max;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmayMinEditField
        function SigmayMinEditFieldValueChanged(app, event)
            app.sigmay_min = app.SigmayMinEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.sigmax_min               = app.sigmay_min;
                app.SigmaxMinEditField.Value = app.sigmax_min;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: SigmayMaxEditField
        function SigmayMaxEditFieldValueChanged(app, event)
            app.sigmay_max = app.SigmayMaxEditField.Value;
            if strcmp(app.CallingApp.localisationAlgorithm,'symmetric gaussian')
                % set sigmay to the same value
                app.sigmax_max               = app.sigmay_max;
                app.SigmaxMaxEditField.Value = app.sigmax_max;
            end
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: UncertaintyxyMinEditField
        function UncertaintyxyMinEditFieldValueChanged(app, event)
            app.uncertainty_xy_min = app.UncertaintyxyMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: UncertaintyxyMaxEditField
        function UncertaintyxyMaxEditFieldValueChanged(app, event)
            app.uncertainty_xy_max = app.UncertaintyxyMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FramesBoundMinEditField
        function FramesBoundMinEditFieldValueChanged(app, event)
            app.framesBound_min = app.FramesBoundMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: FramesBoundMaxEditField
        function FramesBoundMaxEditFieldValueChanged(app, event)
            app.framesBound_max = app.FramesBoundMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ClusterIdMinEditField
        function ClusterIdMinEditFieldValueChanged(app, event)
            app.clusterId_min = app.ClusterIdMinEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Value changed function: ClusterIdMaxEditField
        function ClusterIdMaxEditFieldValueChanged(app, event)
            app.clusterId_max = app.ClusterIdMaxEditField.Value;
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Button pushed function: ClearallfieldsButton
        function ClearallfieldsButtonPushed(app, event)
            app.frame_min          = 0;
            app.frame_max          = inf;
            app.x_min              = 0;
            app.x_max              = inf;
            app.y_min              = 0;
            app.y_max              = inf;
            app.phi_min            = -90;
            app.phi_max            = 90;
            app.theta_min          = 0;
            app.theta_max          = 90;
            app.avgDoLP_min        = 0;
            app.avgDoLP_max        = 1;
            app.netDoLP_min        = 0;
            app.netDoLP_max        = 1;
            app.photons_min        = -inf;
            app.photons_max        = inf;
            app.bkgnd_min          = -inf;
            app.bkgnd_max          = inf;
            app.sigmax_min         = 0;
            app.sigmax_max         = inf;
            app.sigmay_min         = 0;
            app.sigmay_max         = inf;
            app.uncertainty_xy_min = 0;
            app.uncertainty_xy_max = inf;
            % update the fields to display the new limits
            updateFieldValuesFromLimits(app);
            % update filtering accordingly
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Button pushed function: RestoredefaultsButton
        function RestoredefaultsButtonPushed(app, event)
            app.frame_min          = 0;
            app.frame_max          = inf;
            app.x_min              = 0;
            app.x_max              = inf;
            app.y_min              = 0;
            app.y_max              = inf;
            app.phi_min            = -90;
            app.phi_max            = 90;
            app.theta_min          = 0;
            app.theta_max          = 90;
            app.avgDoLP_min        = 0;
            app.avgDoLP_max        = 1;
            app.netDoLP_min        = 0;
            app.netDoLP_max        = 1;
            app.photons_min        = 500;
            app.photons_max        = inf;
            app.bkgnd_min          = -inf;
            app.bkgnd_max          = inf;
            app.sigmax_min         = 0;
            app.sigmax_max         = inf;
            app.sigmay_min         = 0;
            app.sigmay_max         = inf;
            app.uncertainty_xy_min = 0;
            app.uncertainty_xy_max = inf;
            % update the fields to display the new limits
            updateFieldValuesFromLimits(app);
            % update filtering accordingly
            updateKeep(app);
            updateLocalisationNumbersDisplay(app);
        end

        % Button pushed function: SavefilteringButton
        function SavefilteringButtonPushed(app, event)
            if app.CallingApp.folderLocData
                deleteLocalisationsExcludedByCurrentFilteringSettings(app);
                results.locData = app.CallingApp.locData;
                newFileName = strcat(app.CallingApp.filenameLocData,'_filtered');
                app.CallingApp.filenameLocData = newFileName;
                filepathResults = fullfile(app.CallingApp.folderLocData,strcat(newFileName,'.mat'));
                save(filepathResults,'results');
                writeToLog(app.CallingApp,sprintf("\nFiltered localisations were save in:\n'%s'",filepathResults));
                clearErrorLog(app.CallingApp); writeToErrorLog(app.CallingApp,'...');
            else
                writeToErrorLog(app.CallingApp,sprintf("ERROR: Failed to save filtered localisations."));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot1DhistogramPanel = uipanel(app.UIFigure);
            app.Plot1DhistogramPanel.Title = 'Plot 1D histogram';
            app.ParameterLabel = uilabel(app.Plot1DhistogramPanel);
            app.ParameterLabel.Text = 'Parameter:';
            app.ParameterHist1DDropDown = uidropdown(app.Plot1DhistogramPanel);
            app.ParameterHist1DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.ParameterHist1DDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterHist1DDropDownValueChanged, true);
            app.ParameterHist1DDropDown.Value = 'Photons';
            app.Plothistogram1DButton = uibutton(app.Plot1DhistogramPanel, 'push');
            app.Plothistogram1DButton.ButtonPushedFcn = createCallbackFcn(app, @Plothistogram1DButtonPushed, true);
            app.Plothistogram1DButton.Text = 'Plot histogram';
            app.Plot2DhistogramPanel = uipanel(app.UIFigure);
            app.Plot2DhistogramPanel.Title = 'Plot 2D histogram';
            app.Parameter1DropDownLabel = uilabel(app.Plot2DhistogramPanel);
            app.Parameter1DropDownLabel.Text = 'Parameter 1:';
            app.Parameter1Hist2DDropDown = uidropdown(app.Plot2DhistogramPanel);
            app.Parameter1Hist2DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.Parameter1Hist2DDropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter1Hist2DDropDownValueChanged, true);
            app.Parameter1Hist2DDropDown.Value = 'avgDoLP';
            app.Parameter2DropDownLabel = uilabel(app.Plot2DhistogramPanel);
            app.Parameter2DropDownLabel.Text = 'Parameter 2:';
            app.Parameter2Hist2DDropDown = uidropdown(app.Plot2DhistogramPanel);
            app.Parameter2Hist2DDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound'};
            app.Parameter2Hist2DDropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2Hist2DDropDownValueChanged, true);
            app.Parameter2Hist2DDropDown.Value = 'netDoLP';
            app.Plothistogram2DButton = uibutton(app.Plot2DhistogramPanel, 'push');
            app.Plothistogram2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plothistogram2DButtonPushed, true);
            app.Plothistogram2DButton.Text = 'Plot histogram';
            app.PlotcolourcodedscatterplotPanel = uipanel(app.UIFigure);
            app.PlotcolourcodedscatterplotPanel.Title = 'Plot colour-coded scatter plot';
            app.ParameterDropDown_2Label = uilabel(app.PlotcolourcodedscatterplotPanel);
            app.ParameterDropDown_2Label.Text = 'Parameter:';
            app.ParameterScatterPlotDropDown = uidropdown(app.PlotcolourcodedscatterplotPanel);
            app.ParameterScatterPlotDropDown.Items = {'Frame', 'x', 'y', 'Phi', 'Theta', 'avgDoLP', 'netDoLP', 'Photons', 'Bkgnd', 'Sigma x', 'Sigma y', 'Uncertainty xy', 'Angle gaussian', 'Frames bound', 'Cluster id'};
            app.ParameterScatterPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterScatterPlotDropDownValueChanged, true);
            app.ParameterScatterPlotDropDown.Value = 'Phi';
            app.PlotscatterplotButton = uibutton(app.PlotcolourcodedscatterplotPanel, 'push');
            app.PlotscatterplotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotscatterplotButtonPushed, true);
            app.PlotscatterplotButton.Text = 'Plot scatter plot';
            app.SavefilteringButton = uibutton(app.UIFigure, 'push');
            app.SavefilteringButton.ButtonPushedFcn = createCallbackFcn(app, @SavefilteringButtonPushed, true);
            app.SavefilteringButton.Text = 'Save filtering';
            app.FilteringparametersPanel = uipanel(app.UIFigure);
            app.FilteringparametersPanel.Title = 'Filtering parameters';
            app.FrameEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FrameMinEditField.ValueChangedFcn = createCallbackFcn(app, @FrameMinEditFieldValueChanged, true);
            app.xEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.xMinEditField.ValueChangedFcn = createCallbackFcn(app, @xMinEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.yMinEditField.ValueChangedFcn = createCallbackFcn(app, @yMinEditFieldValueChanged, true);
            app.PhiEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhiMinEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMinEditFieldValueChanged, true);
            app.PhiMinEditField.Value = -90;
            app.ThetaEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ThetaMinEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaMinEditFieldValueChanged, true);
            app.avgDoLPEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.avgDoLPEditFieldLabel.Text = 'avgDoLP';
            app.avgDoLPMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.avgDoLPMinEditField.ValueChangedFcn = createCallbackFcn(app, @avgDoLPMinEditFieldValueChanged, true);
            app.netDolpEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.netDolpEditFieldLabel.Text = 'netDolp';
            app.netDoLPMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.netDoLPMinEditField.ValueChangedFcn = createCallbackFcn(app, @netDoLPMinEditFieldValueChanged, true);
            app.PhotonsEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.PhotonsEditFieldLabel.Text = 'Photons';
            app.PhotonsMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhotonsMinEditField.ValueChangedFcn = createCallbackFcn(app, @PhotonsMinEditFieldValueChanged, true);
            app.PhotonsMinEditField.Value = 500;
            app.BkgndEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.BkgndEditFieldLabel.Text = 'Bkgnd';
            app.BkgndMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.BkgndMinEditField.ValueChangedFcn = createCallbackFcn(app, @BkgndMinEditFieldValueChanged, true);
            app.BkgndMinEditField.Value = -Inf;
            app.SigmaxEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.SigmaxEditFieldLabel.Text = 'Sigma x';
            app.SigmaxMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmaxMinEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaxMinEditFieldValueChanged, true);
            app.SigmayEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.SigmayEditFieldLabel.Text = 'Sigma y';
            app.SigmayMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmayMinEditField.ValueChangedFcn = createCallbackFcn(app, @SigmayMinEditFieldValueChanged, true);
            app.UncertaintyxyEditFieldLabel = uilabel(app.FilteringparametersPanel);
            app.UncertaintyxyEditFieldLabel.Text = 'Uncertainty xy';
            app.UncertaintyxyMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.UncertaintyxyMinEditField.ValueChangedFcn = createCallbackFcn(app, @UncertaintyxyMinEditFieldValueChanged, true);
            app.xMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @xMaxEditFieldValueChanged, true);
            app.xMaxEditField.Value = Inf;
            app.yMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.yMaxEditField.ValueChangedFcn = createCallbackFcn(app, @yMaxEditFieldValueChanged, true);
            app.yMaxEditField.Value = Inf;
            app.PhiMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhiMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PhiMaxEditFieldValueChanged, true);
            app.PhiMaxEditField.Value = 90;
            app.FrameMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FrameMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FrameMaxEditFieldValueChanged, true);
            app.FrameMaxEditField.Value = Inf;
            app.ThetaMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ThetaMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaMaxEditFieldValueChanged, true);
            app.ThetaMaxEditField.Value = 90;
            app.netDoLPMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.netDoLPMaxEditField.ValueChangedFcn = createCallbackFcn(app, @netDoLPMaxEditFieldValueChanged, true);
            app.netDoLPMaxEditField.Value = 1;
            app.PhotonsMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.PhotonsMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PhotonsMaxEditFieldValueChanged, true);
            app.PhotonsMaxEditField.Value = Inf;
            app.BkgndMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.BkgndMaxEditField.ValueChangedFcn = createCallbackFcn(app, @BkgndMaxEditFieldValueChanged, true);
            app.BkgndMaxEditField.Value = Inf;
            app.SigmaxMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmaxMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaxMaxEditFieldValueChanged, true);
            app.SigmaxMaxEditField.Value = Inf;
            app.SigmayMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.SigmayMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SigmayMaxEditFieldValueChanged, true);
            app.SigmayMaxEditField.Value = Inf;
            app.avgDoLPMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.avgDoLPMaxEditField.ValueChangedFcn = createCallbackFcn(app, @avgDoLPMaxEditFieldValueChanged, true);
            app.avgDoLPMaxEditField.Value = 1;
            app.UncertaintyxyMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.UncertaintyxyMaxEditField.ValueChangedFcn = createCallbackFcn(app, @UncertaintyxyMaxEditFieldValueChanged, true);
            app.UncertaintyxyMaxEditField.Value = 100;
            app.MinLabel = uilabel(app.FilteringparametersPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.FilteringparametersPanel);
            app.MaxLabel.Text = 'Max';
            app.FramesBoundMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FramesBoundMaxEditField.ValueChangedFcn = createCallbackFcn(app, @FramesBoundMaxEditFieldValueChanged, true);
            app.FramesBoundMaxEditField.Value = Inf;
            app.FramesboundLabel = uilabel(app.FilteringparametersPanel);
            app.FramesboundLabel.Text = 'Frames bound';
            app.FramesBoundMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.FramesBoundMinEditField.ValueChangedFcn = createCallbackFcn(app, @FramesBoundMinEditFieldValueChanged, true);
            app.ClusterIdMaxEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ClusterIdMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterIdMaxEditFieldValueChanged, true);
            app.ClusterIdMaxEditField.Value = Inf;
            app.FramesboundLabel_2 = uilabel(app.FilteringparametersPanel);
            app.FramesboundLabel_2.Text = 'Cluster id';
            app.ClusterIdMinEditField = uieditfield(app.FilteringparametersPanel, 'numeric');
            app.ClusterIdMinEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterIdMinEditFieldValueChanged, true);
            app.ClusterIdMinEditField.Value = -Inf;
            app.ClearallfieldsButton = uibutton(app.UIFigure, 'push');
            app.ClearallfieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallfieldsButtonPushed, true);
            app.ClearallfieldsButton.Text = 'Clear all fields';
            app.RestoredefaultsButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultsButtonPushed, true);
            app.RestoredefaultsButton.Text = 'Restore defaults';
            app.NumberoflocalisationsbeforefilteringEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflocalisationsbeforefilteringEditFieldLabel.Text = 'Number of localisations before filtering:';
            app.NumberoflocalisationsbeforefilteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflocalisationsbeforefilteringEditField.Editable = 'off';
            app.NumberoflocalisationsafterfilteringEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflocalisationsafterfilteringEditFieldLabel.Text = 'Number of localisations after filtering:';
            app.NumberoflocalisationsafterfilteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflocalisationsafterfilteringEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFiltering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end